classdef Results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ageofVotesLabel           matlab.ui.control.Label
        VotesReceivedLabel        matlab.ui.control.Label
        Label_10                  matlab.ui.control.Label
        Label_9                   matlab.ui.control.Label
        Label_8                   matlab.ui.control.Label
        Label_7                   matlab.ui.control.Label
        Label_6                   matlab.ui.control.Label
        Label_5                   matlab.ui.control.Label
        Label_4                   matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        PartyLogoLabel            matlab.ui.control.Label
        CandidatesPartyLabel      matlab.ui.control.Label
        CandidateNameLabel        matlab.ui.control.Label
        SNoLabel                  matlab.ui.control.Label
        ResultsLabel              matlab.ui.control.Label
        TotalVotesEditField       matlab.ui.control.NumericEditField
        TotalVotesEditFieldLabel  matlab.ui.control.Label
        vp10                      matlab.ui.control.NumericEditField
        vp9                       matlab.ui.control.NumericEditField
        vp8                       matlab.ui.control.NumericEditField
        vp7                       matlab.ui.control.NumericEditField
        vp6                       matlab.ui.control.NumericEditField
        vp5                       matlab.ui.control.NumericEditField
        vp4                       matlab.ui.control.NumericEditField
        vp3                       matlab.ui.control.NumericEditField
        vp2                       matlab.ui.control.NumericEditField
        vp1                       matlab.ui.control.NumericEditField
        v10                       matlab.ui.control.NumericEditField
        v9                        matlab.ui.control.NumericEditField
        v8                        matlab.ui.control.NumericEditField
        v7                        matlab.ui.control.NumericEditField
        v6                        matlab.ui.control.NumericEditField
        v5                        matlab.ui.control.NumericEditField
        v4                        matlab.ui.control.NumericEditField
        v3                        matlab.ui.control.NumericEditField
        v2                        matlab.ui.control.NumericEditField
        v1                        matlab.ui.control.NumericEditField
        cN1                       matlab.ui.control.EditField
        cN6                       matlab.ui.control.EditField
        cN7                       matlab.ui.control.EditField
        cN8                       matlab.ui.control.EditField
        cN9                       matlab.ui.control.EditField
        cN10                      matlab.ui.control.EditField
        cN5                       matlab.ui.control.EditField
        cN4                       matlab.ui.control.EditField
        cN3                       matlab.ui.control.EditField
        cN2                       matlab.ui.control.EditField
        cP1                       matlab.ui.control.EditField
        cP2                       matlab.ui.control.EditField
        cP3                       matlab.ui.control.EditField
        cP4                       matlab.ui.control.EditField
        cP5                       matlab.ui.control.EditField
        cP6                       matlab.ui.control.EditField
        cP7                       matlab.ui.control.EditField
        cP8                       matlab.ui.control.EditField
        cP9                       matlab.ui.control.EditField
        cP10                      matlab.ui.control.EditField
        cL1                       matlab.ui.control.Image
        cL2                       matlab.ui.control.Image
        cL3                       matlab.ui.control.Image
        cL4                       matlab.ui.control.Image
        cL5                       matlab.ui.control.Image
        cL6                       matlab.ui.control.Image
        cL7                       matlab.ui.control.Image
        cL8                       matlab.ui.control.Image
        cL9                       matlab.ui.control.Image
        cL10                      matlab.ui.control.Image
    end

    
    properties (Access = private)
        CallingApp
        
        cellarr
        strarr
        name1
        name2
        name3
        name4
        name5
        name6
        name7
        name8
        name9
        name10="NOTA";
        party1
        party2
        party3
        party4
        party5
        party6
        party7
        party8
        party9
        party10="NOTA";
        votes1=0
        votes2=0
        votes3=0
        votes4=0
        votes5=0
        votes6=0
        votes7=0
        votes8=0
        votes9=0
        votes10=0
        totalvotes
    end
    
    methods (Access = public)
        
        function loadvals(app)
            
            app.cellarr=readcell('Election.xlsx','Range','A2:B10');

            app.strarr=string(app.cellarr);
            
            app.name1=app.strarr(1,1);
            app.name2=app.strarr(2,1);
            app.name3=app.strarr(3,1);
            app.name4=app.strarr(4,1);
            app.name5=app.strarr(5,1);
            app.name6=app.strarr(6,1);
            app.name7=app.strarr(7,1);
            app.name8=app.strarr(8,1);
            app.name9=app.strarr(9,1);
            
            app.party1=app.strarr(1,2);
            app.party2=app.strarr(2,2);
            app.party3=app.strarr(3,2);
            app.party4=app.strarr(4,2);
            app.party5=app.strarr(5,2);
            app.party6=app.strarr(6,2);
            app.party7=app.strarr(7,2);
            app.party8=app.strarr(8,2);
            app.party9=app.strarr(9,2);
            
            app.cL1.ImageSource = strcat(app.party1,'.jpg');
            app.cL2.ImageSource = strcat(app.party2,'.jpg');
            app.cL3.ImageSource = strcat(app.party3,'.jpg');
            app.cL4.ImageSource = strcat(app.party4,'.jpg');
            app.cL5.ImageSource = strcat(app.party5,'.jpg');
            app.cL6.ImageSource = strcat(app.party6,'.jpg');
            app.cL7.ImageSource = strcat(app.party7,'.jpg');
            app.cL8.ImageSource = strcat(app.party8,'.jpg');
            app.cL9.ImageSource = strcat(app.party9,'.jpg');
            app.cL10.ImageSource = strcat(app.party10,'.jpg');
        end
    end
    
    methods (Access = private)
        
        function hideunused(app)
            if(ismissing(app.name1)==1)
                app.cL1.ImageSource ='empty.jpg';
            end
            if(ismissing(app.name2)==1)
                app.cL2.ImageSource ='empty.jpg';
            end
            if(ismissing(app.name3)==1)
                app.cL3.ImageSource ='empty.jpg';
            end
            if(ismissing(app.name4)==1)
                app.cL4.ImageSource ='empty.jpg';
            end
            if(ismissing(app.name5)==1)
                app.cL5.ImageSource ='empty.jpg';
            end
            if(ismissing(app.name6)==1)
                app.cL6.ImageSource ='empty.jpg';
            end
            if(ismissing(app.name7)==1)
                app.cL7.ImageSource ='empty.jpg';
            end
            if(ismissing(app.name8)==1)
                app.cL8.ImageSource ='empty.jpg';
            end
            if(ismissing(app.name9)==1)
                app.cL9.ImageSource ='empty.jpg';
            end
        end
        
        function dobold(app)
            if((app.votes1*100.0/app.totalvotes)>50.0)
                app.cN1.FontWeight = 'bold';
                app.cP1.FontWeight = 'bold';
                app.v1.FontWeight = 'bold';
                app.vp1.FontWeight = 'bold';
            end
            if((app.votes2*100.0/app.totalvotes)>50.0)
                app.cN2.FontWeight = 'bold';
                app.cP2.FontWeight = 'bold';
                app.v2.FontWeight = 'bold';
                app.vp2.FontWeight = 'bold';
            end
            if((app.votes3*100.0/app.totalvotes)>50.0)
                app.cN3.FontWeight = 'bold';
                app.cP3.FontWeight = 'bold';
                app.v3.FontWeight = 'bold';
                app.vp3.FontWeight = 'bold';
            end
            if((app.votes4*100.0/app.totalvotes)>50.0)
                app.cN4.FontWeight = 'bold';
                app.cP4.FontWeight = 'bold';
                app.v4.FontWeight = 'bold';
                app.vp4.FontWeight = 'bold';
            end
            if((app.votes5*100.0/app.totalvotes)>50.0)
                app.cN5.FontWeight = 'bold';
                app.cP5.FontWeight = 'bold';
                app.v5.FontWeight = 'bold';
                app.vp5.FontWeight = 'bold';
            end
            if((app.votes6*100.0/app.totalvotes)>50.0)
                app.cN6.FontWeight = 'bold';
                app.cP6.FontWeight = 'bold';
                app.v6.FontWeight = 'bold';
                app.vp6.FontWeight = 'bold';
            end
            if((app.votes7*100.0/app.totalvotes)>50.0)
                app.cN7.FontWeight = 'bold';
                app.cP7.FontWeight = 'bold';
                app.v7.FontWeight = 'bold';
                app.vp7.FontWeight = 'bold';
            end
            if((app.votes8*100.0/app.totalvotes)>50.0)
                app.cN8.FontWeight = 'bold';
                app.cP8.FontWeight = 'bold';
                app.v8.FontWeight = 'bold';
                app.vp8.FontWeight = 'bold';
            end
            if((app.votes9*100.0/app.totalvotes)>50.0)
                app.cN9.FontWeight = 'bold';
                app.cP9.FontWeight = 'bold';
                app.v9.FontWeight = 'bold';
                app.vp9.FontWeight = 'bold';
            end
            if((app.votes10*100.0/app.totalvotes)>50.0)
                app.cN10.FontWeight = 'bold';
                app.cP10.FontWeight = 'bold';
                app.v10.FontWeight = 'bold';
                app.vp10.FontWeight = 'bold';
            end
           
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, v1, v2, v3, v4, v5, v6, v7, v8, v9, v10)
            app.CallingApp=mainapp;
            
            addpath("Databases\");
            
            loadvals(app);
            
            app.cN1.Value=app.name1;
            app.cN2.Value=app.name2;
            app.cN3.Value=app.name3;
            app.cN4.Value=app.name4;
            app.cN5.Value=app.name5;
            app.cN6.Value=app.name6;
            app.cN7.Value=app.name7;
            app.cN8.Value=app.name8;
            app.cN9.Value=app.name9;
            app.cN10.Value=app.name10;
            
            app.cP1.Value=app.party1;
            app.cP2.Value=app.party2;
            app.cP3.Value=app.party3;
            app.cP4.Value=app.party4;
            app.cP5.Value=app.party5;
            app.cP6.Value=app.party6;
            app.cP7.Value=app.party7;
            app.cP8.Value=app.party8;
            app.cP9.Value=app.party9;
            app.cP10.Value=app.party10;
            
            app.votes1=v1;
            app.votes2=v2;
            app.votes3=v3;
            app.votes4=v4;
            app.votes5=v5;
            app.votes6=v6;
            app.votes7=v7;
            app.votes8=v8;
            app.votes9=v9;
            app.votes10=v10;
            app.totalvotes=v1+v2+v3+v4+v5+v6+v7+v8+v9+v10;
            
            hideunused(app);
            closemainapp(mainapp);
            
            app.v1.Value=app.votes1;
            app.v2.Value=app.votes2;
            app.v3.Value=app.votes3;
            app.v4.Value=app.votes4;
            app.v5.Value=app.votes5;
            app.v6.Value=app.votes6;
            app.v7.Value=app.votes7;
            app.v8.Value=app.votes8;
            app.v9.Value=app.votes9;
            app.v10.Value=app.votes10;
            app.TotalVotesEditField.Value=app.totalvotes;
            
            app.vp1.Value=(app.votes1*100/app.totalvotes);
            app.vp2.Value=(app.votes2*100/app.totalvotes);
            app.vp3.Value=(app.votes3*100/app.totalvotes);
            app.vp4.Value=(app.votes4*100/app.totalvotes);
            app.vp5.Value=(app.votes5*100/app.totalvotes);
            app.vp6.Value=(app.votes6*100/app.totalvotes);
            app.vp7.Value=(app.votes7*100/app.totalvotes);
            app.vp8.Value=(app.votes8*100/app.totalvotes);
            app.vp9.Value=(app.votes9*100/app.totalvotes);
            app.vp10.Value=(app.votes10*100/app.totalvotes);
            
            dobold(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.cL10 = uiimage(app.UIFigure);
            app.cL9 = uiimage(app.UIFigure);
            app.cL8 = uiimage(app.UIFigure);
            app.cL7 = uiimage(app.UIFigure);
            app.cL6 = uiimage(app.UIFigure);
            app.cL5 = uiimage(app.UIFigure);
            app.cL4 = uiimage(app.UIFigure);
            app.cL3 = uiimage(app.UIFigure);
            app.cL2 = uiimage(app.UIFigure);
            app.cL1 = uiimage(app.UIFigure);
            app.cP10 = uieditfield(app.UIFigure, 'text');
            app.cP10.Editable = 'off';
            app.cP9 = uieditfield(app.UIFigure, 'text');
            app.cP9.Editable = 'off';
            app.cP8 = uieditfield(app.UIFigure, 'text');
            app.cP8.Editable = 'off';
            app.cP7 = uieditfield(app.UIFigure, 'text');
            app.cP7.Editable = 'off';
            app.cP6 = uieditfield(app.UIFigure, 'text');
            app.cP6.Editable = 'off';
            app.cP5 = uieditfield(app.UIFigure, 'text');
            app.cP5.Editable = 'off';
            app.cP4 = uieditfield(app.UIFigure, 'text');
            app.cP4.Editable = 'off';
            app.cP3 = uieditfield(app.UIFigure, 'text');
            app.cP3.Editable = 'off';
            app.cP2 = uieditfield(app.UIFigure, 'text');
            app.cP2.Editable = 'off';
            app.cP1 = uieditfield(app.UIFigure, 'text');
            app.cP1.Editable = 'off';
            app.cN2 = uieditfield(app.UIFigure, 'text');
            app.cN2.Editable = 'off';
            app.cN3 = uieditfield(app.UIFigure, 'text');
            app.cN3.Editable = 'off';
            app.cN4 = uieditfield(app.UIFigure, 'text');
            app.cN4.Editable = 'off';
            app.cN5 = uieditfield(app.UIFigure, 'text');
            app.cN5.Editable = 'off';
            app.cN10 = uieditfield(app.UIFigure, 'text');
            app.cN10.Editable = 'off';
            app.cN9 = uieditfield(app.UIFigure, 'text');
            app.cN9.Editable = 'off';
            app.cN8 = uieditfield(app.UIFigure, 'text');
            app.cN8.Editable = 'off';
            app.cN7 = uieditfield(app.UIFigure, 'text');
            app.cN7.Editable = 'off';
            app.cN6 = uieditfield(app.UIFigure, 'text');
            app.cN6.Editable = 'off';
            app.cN1 = uieditfield(app.UIFigure, 'text');
            app.cN1.Editable = 'off';
            app.v1 = uieditfield(app.UIFigure, 'numeric');
            app.v1.Editable = 'off';
            app.v2 = uieditfield(app.UIFigure, 'numeric');
            app.v2.Editable = 'off';
            app.v3 = uieditfield(app.UIFigure, 'numeric');
            app.v3.Editable = 'off';
            app.v4 = uieditfield(app.UIFigure, 'numeric');
            app.v4.Editable = 'off';
            app.v5 = uieditfield(app.UIFigure, 'numeric');
            app.v5.Editable = 'off';
            app.v6 = uieditfield(app.UIFigure, 'numeric');
            app.v6.Editable = 'off';
            app.v7 = uieditfield(app.UIFigure, 'numeric');
            app.v7.Editable = 'off';
            app.v8 = uieditfield(app.UIFigure, 'numeric');
            app.v8.Editable = 'off';
            app.v9 = uieditfield(app.UIFigure, 'numeric');
            app.v9.Editable = 'off';
            app.v10 = uieditfield(app.UIFigure, 'numeric');
            app.v10.Editable = 'off';
            app.vp1 = uieditfield(app.UIFigure, 'numeric');
            app.vp1.Editable = 'off';
            app.vp2 = uieditfield(app.UIFigure, 'numeric');
            app.vp2.Editable = 'off';
            app.vp3 = uieditfield(app.UIFigure, 'numeric');
            app.vp3.Editable = 'off';
            app.vp4 = uieditfield(app.UIFigure, 'numeric');
            app.vp4.Editable = 'off';
            app.vp5 = uieditfield(app.UIFigure, 'numeric');
            app.vp5.Editable = 'off';
            app.vp6 = uieditfield(app.UIFigure, 'numeric');
            app.vp6.Editable = 'off';
            app.vp7 = uieditfield(app.UIFigure, 'numeric');
            app.vp7.Editable = 'off';
            app.vp8 = uieditfield(app.UIFigure, 'numeric');
            app.vp8.Editable = 'off';
            app.vp9 = uieditfield(app.UIFigure, 'numeric');
            app.vp9.Editable = 'off';
            app.vp10 = uieditfield(app.UIFigure, 'numeric');
            app.vp10.Editable = 'off';
            app.TotalVotesEditFieldLabel = uilabel(app.UIFigure);
            app.TotalVotesEditFieldLabel.Text = 'Total Votes';
            app.TotalVotesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalVotesEditField.Editable = 'off';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.SNoLabel = uilabel(app.UIFigure);
            app.SNoLabel.Text = 'S No.';
            app.CandidateNameLabel = uilabel(app.UIFigure);
            app.CandidateNameLabel.Text = {'Candidate Name'; ''};
            app.CandidatesPartyLabel = uilabel(app.UIFigure);
            app.CandidatesPartyLabel.Text = {'Candidate''s Party'; ''};
            app.PartyLogoLabel = uilabel(app.UIFigure);
            app.PartyLogoLabel.Text = {'Party Logo'; ''};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1.';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'2.'; ''};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'3.'; ''};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'4.'; ''};
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = {'5.'; ''};
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = {'6.'; ''};
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = {'7.'; ''};
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = {'8.'; ''};
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = {'9.'; ''};
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = {'10.'; ''};
            app.VotesReceivedLabel = uilabel(app.UIFigure);
            app.VotesReceivedLabel.Text = 'Votes Received';
            app.ageofVotesLabel = uilabel(app.UIFigure);
            app.ageofVotesLabel.Text = {'%age of Votes'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Results(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end