classdef VotingMachine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PartyLogoLabel                matlab.ui.control.Label
        CandidatesPartyLabel          matlab.ui.control.Label
        CandidateNameLabel            matlab.ui.control.Label
        SNoLabel                      matlab.ui.control.Label
        Label_10                      matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        ElectronicVotingMachineLabel  matlab.ui.control.Label
        ResultsButton                 matlab.ui.control.Button
        VotingStateLampLabel          matlab.ui.control.Label
        VotingStateLamp               matlab.ui.control.Lamp
        cN1                           matlab.ui.control.EditField
        cN6                           matlab.ui.control.EditField
        cN7                           matlab.ui.control.EditField
        cN8                           matlab.ui.control.EditField
        cN9                           matlab.ui.control.EditField
        cN10                          matlab.ui.control.EditField
        cN5                           matlab.ui.control.EditField
        cN4                           matlab.ui.control.EditField
        cN3                           matlab.ui.control.EditField
        cN2                           matlab.ui.control.EditField
        cP1                           matlab.ui.control.EditField
        cP2                           matlab.ui.control.EditField
        cP3                           matlab.ui.control.EditField
        cP4                           matlab.ui.control.EditField
        cP5                           matlab.ui.control.EditField
        cP6                           matlab.ui.control.EditField
        cP7                           matlab.ui.control.EditField
        cP8                           matlab.ui.control.EditField
        cP9                           matlab.ui.control.EditField
        cP10                          matlab.ui.control.EditField
        cL1                           matlab.ui.control.Image
        cL2                           matlab.ui.control.Image
        cL3                           matlab.ui.control.Image
        cL4                           matlab.ui.control.Image
        cL5                           matlab.ui.control.Image
        cL6                           matlab.ui.control.Image
        cL7                           matlab.ui.control.Image
        cL8                           matlab.ui.control.Image
        cL9                           matlab.ui.control.Image
        cL10                          matlab.ui.control.Image
        Lamp_1                        matlab.ui.control.Lamp
        Lamp_2                        matlab.ui.control.Lamp
        Lamp_3                        matlab.ui.control.Lamp
        Lamp_4                        matlab.ui.control.Lamp
        Lamp_5                        matlab.ui.control.Lamp
        Lamp_6                        matlab.ui.control.Lamp
        Lamp_7                        matlab.ui.control.Lamp
        Lamp_8                        matlab.ui.control.Lamp
        Lamp_9                        matlab.ui.control.Lamp
        Lamp_10                       matlab.ui.control.Lamp
        VoteButton_1                  matlab.ui.control.Button
        VoteButton_2                  matlab.ui.control.Button
        VoteButton_3                  matlab.ui.control.Button
        VoteButton_4                  matlab.ui.control.Button
        VoteButton_5                  matlab.ui.control.Button
        VoteButton_6                  matlab.ui.control.Button
        VoteButton_7                  matlab.ui.control.Button
        VoteButton_8                  matlab.ui.control.Button
        VoteButton_9                  matlab.ui.control.Button
        VoteButton_10                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        cellarr
        strarr
        name1
        name2
        name3
        name4
        name5
        name6
        name7
        name8
        name9
        name10="NOTA";
        party1
        party2
        party3
        party4
        party5
        party6
        party7
        party8
        party9
        party10="NOTA";
        votes1=0
        votes2=0
        votes3=0
        votes4=0
        votes5=0
        votes6=0
        votes7=0
        votes8=0
        votes9=0
        votes10=0
        
        result
        admincode
        
    end
    
    methods (Access = public)
        
        function loadvals(app)
            
            app.cellarr=readcell('Election.xlsx','Range','A2:B10');

            app.strarr=string(app.cellarr);
            
            app.name1=app.strarr(1,1);
            app.name2=app.strarr(2,1);
            app.name3=app.strarr(3,1);
            app.name4=app.strarr(4,1);
            app.name5=app.strarr(5,1);
            app.name6=app.strarr(6,1);
            app.name7=app.strarr(7,1);
            app.name8=app.strarr(8,1);
            app.name9=app.strarr(9,1);
            
            app.party1=app.strarr(1,2);
            app.party2=app.strarr(2,2);
            app.party3=app.strarr(3,2);
            app.party4=app.strarr(4,2);
            app.party5=app.strarr(5,2);
            app.party6=app.strarr(6,2);
            app.party7=app.strarr(7,2);
            app.party8=app.strarr(8,2);
            app.party9=app.strarr(9,2);
            
            app.cL1.ImageSource = strcat(app.party1,'.jpg');
            app.cL2.ImageSource = strcat(app.party2,'.jpg');
            app.cL3.ImageSource = strcat(app.party3,'.jpg');
            app.cL4.ImageSource = strcat(app.party4,'.jpg');
            app.cL5.ImageSource = strcat(app.party5,'.jpg');
            app.cL6.ImageSource = strcat(app.party6,'.jpg');
            app.cL7.ImageSource = strcat(app.party7,'.jpg');
            app.cL8.ImageSource = strcat(app.party8,'.jpg');
            app.cL9.ImageSource = strcat(app.party9,'.jpg');
            app.cL10.ImageSource = strcat(app.party10,'.jpg');
        end
    end
    
    
    methods (Access = public)
        
        function disablebuttons(app)
            
               set(app.VoteButton_1,'Enable','off');
               set(app.VoteButton_2,'Enable','off');
               set(app.VoteButton_3,'Enable','off');
               set(app.VoteButton_4,'Enable','off');
               set(app.VoteButton_5,'Enable','off');
               set(app.VoteButton_6,'Enable','off');
               set(app.VoteButton_7,'Enable','off');
               set(app.VoteButton_8,'Enable','off');
               set(app.VoteButton_9,'Enable','off');
               set(app.VoteButton_10,'Enable','off');
               set(app.ResultsButton,'Enable','off');
               
               app.VotingStateLamp.Color = [0.6353 0.0784 0.1843];
            
        end
    end


    methods (Access = public)
        
        function enablebuttons(app)
            
            try
               set(app.VoteButton_1,'Enable','on');
               set(app.VoteButton_2,'Enable','on');
               set(app.VoteButton_3,'Enable','on');
               set(app.VoteButton_4,'Enable','on');
               set(app.VoteButton_5,'Enable','on');
               set(app.VoteButton_6,'Enable','on');
               set(app.VoteButton_7,'Enable','on');
               set(app.VoteButton_8,'Enable','on');
               set(app.VoteButton_9,'Enable','on');
               set(app.VoteButton_10,'Enable','on');
               set(app.ResultsButton,'Enable','on');
               
               app.VotingStateLamp.Color = [0.00 1.00 0.00];
            catch
            end
        end
    end
    
    methods (Access = public)
        
        function beeping(app)
            app.VotingStateLamp.Color = [0.6353 0.0784 0.1843];
            amp=10; 
            fs=20500;
            duration=0.1;
            freq=12000;
            values=0:1/fs:duration;
            a=amp*sin(2*pi* freq*values);
            sound(a)
            
        end
     end
    
    methods (Access = public)
        
        function closemainapp(app)
            closepasscode(app.admincode);
            delete(app);            
        end
    end
    
    methods (Access = public)
        
        function callresult(app)
            try
             app.result=Results(app,app.votes1,app.votes2,app.votes3,app.votes4,app.votes5,app.votes6,app.votes7,app.votes8,app.votes9,app.votes10);
            catch
            end
        end
    end
    
    methods (Access = private)
        
        function hideunused(app)
            if(ismissing(app.name1)==1)
                app.VoteButton_1.Visible = 'off';
                app.cL1.ImageSource ='empty.jpg';
            end
            if(ismissing(app.name2)==1)
                app.VoteButton_2.Visible = 'off';
                app.cL2.ImageSource ='empty.jpg';
            end
            if(ismissing(app.name3)==1)
                app.VoteButton_3.Visible = 'off';
                app.cL3.ImageSource ='empty.jpg';
            end
            if(ismissing(app.name4)==1)
                app.VoteButton_4.Visible = 'off';
                app.cL4.ImageSource ='empty.jpg';
            end
            if(ismissing(app.name5)==1)
                app.VoteButton_5.Visible = 'off';
                app.cL5.ImageSource ='empty.jpg';
            end
            if(ismissing(app.name6)==1)
                app.VoteButton_6.Visible = 'off';
                app.cL6.ImageSource ='empty.jpg';
            end
            if(ismissing(app.name7)==1)
                app.VoteButton_7.Visible = 'off';
                app.cL7.ImageSource ='empty.jpg';
            end
            if(ismissing(app.name8)==1)
                app.VoteButton_8.Visible = 'off';
                app.cL8.ImageSource ='empty.jpg';
            end
            if(ismissing(app.name9)==1)
                app.VoteButton_9.Visible = 'off';
                app.cL9.ImageSource ='empty.jpg';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            addpath("Databases\");
            
            loadvals(app);
            
            app.cN1.Value=app.name1;
            app.cN2.Value=app.name2;
            app.cN3.Value=app.name3;
            app.cN4.Value=app.name4;
            app.cN5.Value=app.name5;
            app.cN6.Value=app.name6;
            app.cN7.Value=app.name7;
            app.cN8.Value=app.name8;
            app.cN9.Value=app.name9;
            app.cN10.Value=app.name10;
            
            app.cP1.Value=app.party1;
            app.cP2.Value=app.party2;
            app.cP3.Value=app.party3;
            app.cP4.Value=app.party4;
            app.cP5.Value=app.party5;
            app.cP6.Value=app.party6;
            app.cP7.Value=app.party7;
            app.cP8.Value=app.party8;
            app.cP9.Value=app.party9;
            app.cP10.Value=app.party10;
                        
            hideunused(app);
            
        end

        % Button pushed function: VoteButton_1
        function VoteButton_1Pushed(app, event)
            app.votes1=app.votes1+1;
            app.Lamp_1.Color = [0.00 1.00 0.00];
            disablebuttons(app);
            beeping(app);
            pause(1);
            app.Lamp_1.Color = [0.6353 0.0784 0.1843];
            pause(4);
            enablebuttons(app);
        end

        % Button pushed function: VoteButton_2
        function VoteButton_2Pushed(app, event)
            app.votes2=app.votes2+1;
            app.Lamp_2.Color = [0.00 1.00 0.00];
            disablebuttons(app);
            beeping(app);
            pause(1);
            app.Lamp_2.Color = [0.6353 0.0784 0.1843];
            pause(4);
            enablebuttons(app);
        end

        % Button pushed function: VoteButton_3
        function VoteButton_3Pushed(app, event)
            app.votes3=app.votes3+1;
            app.Lamp_3.Color = [0.00 1.00 0.00];
            disablebuttons(app);
            beeping(app);
            pause(1);
            app.Lamp_3.Color = [0.6353 0.0784 0.1843];
            pause(4);
            enablebuttons(app);
        end

        % Button pushed function: VoteButton_4
        function VoteButton_4Pushed(app, event)
            app.votes4=app.votes4+1;
            app.Lamp_4.Color = [0.00 1.00 0.00];
            disablebuttons(app);
            beeping(app);
            pause(1);
            app.Lamp_4.Color = [0.6353 0.0784 0.1843];
            pause(4);
            enablebuttons(app);
        end

        % Button pushed function: VoteButton_5
        function VoteButton_5Pushed(app, event)
            app.votes5=app.votes5+1;
            app.Lamp_5.Color = [0.00 1.00 0.00];
            disablebuttons(app);
            beeping(app);
            pause(1);
            app.Lamp_5.Color = [0.6353 0.0784 0.1843];
            pause(4);
            enablebuttons(app);
        end

        % Button pushed function: VoteButton_6
        function VoteButton_6Pushed(app, event)
            app.votes6=app.votes6+1;
            app.Lamp_6.Color = [0.00 1.00 0.00];
            disablebuttons(app);
            beeping(app);
            pause(1);
            app.Lamp_6.Color = [0.6353 0.0784 0.1843];
            pause(4);
            enablebuttons(app);
        end

        % Button pushed function: VoteButton_7
        function VoteButton_7Pushed(app, event)
            app.votes7=app.votes7+1;
            app.Lamp_7.Color = [0.00 1.00 0.00];
            disablebuttons(app);
            beeping(app);
            pause(1);
            app.Lamp_7.Color = [0.6353 0.0784 0.1843];
            pause(4);
            enablebuttons(app);
        end

        % Button pushed function: VoteButton_8
        function VoteButton_8Pushed(app, event)
            app.votes8=app.votes8+1;
            app.Lamp_8.Color = [0.00 1.00 0.00];
            disablebuttons(app);
            beeping(app);
            pause(1);
            app.Lamp_8.Color = [0.6353 0.0784 0.1843];
            pause(4);
            enablebuttons(app);
        end

        % Button pushed function: VoteButton_9
        function VoteButton_9Pushed(app, event)
            app.votes9=app.votes9+1;
            app.Lamp_9.Color = [0.00 1.00 0.00];
            disablebuttons(app);
            beeping(app);
            pause(1);
            app.Lamp_9.Color = [0.6353 0.0784 0.1843];
            pause(4);
            enablebuttons(app);
        end

        % Button pushed function: VoteButton_10
        function VoteButton_10Pushed(app, event)
            app.votes10=app.votes10+1;
            app.Lamp_10.Color = [0.00 1.00 0.00];
            disablebuttons(app);
            beeping(app);
            pause(1);
            app.Lamp_10.Color = [0.6353 0.0784 0.1843];
            pause(4);
            enablebuttons(app);
        end

        % Button pushed function: ResultsButton
        function ResultsButtonPushed(app, event)
            disablebuttons(app);
            app.admincode=adminpasscode(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VoteButton_10 = uibutton(app.UIFigure, 'push');
            app.VoteButton_10.ButtonPushedFcn = createCallbackFcn(app, @VoteButton_10Pushed, true);
            app.VoteButton_10.Text = 'Vote';
            app.VoteButton_9 = uibutton(app.UIFigure, 'push');
            app.VoteButton_9.ButtonPushedFcn = createCallbackFcn(app, @VoteButton_9Pushed, true);
            app.VoteButton_9.Text = 'Vote';
            app.VoteButton_8 = uibutton(app.UIFigure, 'push');
            app.VoteButton_8.ButtonPushedFcn = createCallbackFcn(app, @VoteButton_8Pushed, true);
            app.VoteButton_8.Text = 'Vote';
            app.VoteButton_7 = uibutton(app.UIFigure, 'push');
            app.VoteButton_7.ButtonPushedFcn = createCallbackFcn(app, @VoteButton_7Pushed, true);
            app.VoteButton_7.Text = 'Vote';
            app.VoteButton_6 = uibutton(app.UIFigure, 'push');
            app.VoteButton_6.ButtonPushedFcn = createCallbackFcn(app, @VoteButton_6Pushed, true);
            app.VoteButton_6.Text = 'Vote';
            app.VoteButton_5 = uibutton(app.UIFigure, 'push');
            app.VoteButton_5.ButtonPushedFcn = createCallbackFcn(app, @VoteButton_5Pushed, true);
            app.VoteButton_5.Text = 'Vote';
            app.VoteButton_4 = uibutton(app.UIFigure, 'push');
            app.VoteButton_4.ButtonPushedFcn = createCallbackFcn(app, @VoteButton_4Pushed, true);
            app.VoteButton_4.Text = 'Vote';
            app.VoteButton_3 = uibutton(app.UIFigure, 'push');
            app.VoteButton_3.ButtonPushedFcn = createCallbackFcn(app, @VoteButton_3Pushed, true);
            app.VoteButton_3.Text = 'Vote';
            app.VoteButton_2 = uibutton(app.UIFigure, 'push');
            app.VoteButton_2.ButtonPushedFcn = createCallbackFcn(app, @VoteButton_2Pushed, true);
            app.VoteButton_2.Text = 'Vote';
            app.VoteButton_1 = uibutton(app.UIFigure, 'push');
            app.VoteButton_1.ButtonPushedFcn = createCallbackFcn(app, @VoteButton_1Pushed, true);
            app.VoteButton_1.Text = 'Vote';
            app.Lamp_10 = uilamp(app.UIFigure);
            app.Lamp_9 = uilamp(app.UIFigure);
            app.Lamp_8 = uilamp(app.UIFigure);
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_1 = uilamp(app.UIFigure);
            app.cL10 = uiimage(app.UIFigure);
            app.cL9 = uiimage(app.UIFigure);
            app.cL8 = uiimage(app.UIFigure);
            app.cL7 = uiimage(app.UIFigure);
            app.cL6 = uiimage(app.UIFigure);
            app.cL5 = uiimage(app.UIFigure);
            app.cL4 = uiimage(app.UIFigure);
            app.cL3 = uiimage(app.UIFigure);
            app.cL2 = uiimage(app.UIFigure);
            app.cL1 = uiimage(app.UIFigure);
            app.cP10 = uieditfield(app.UIFigure, 'text');
            app.cP10.Editable = 'off';
            app.cP9 = uieditfield(app.UIFigure, 'text');
            app.cP9.Editable = 'off';
            app.cP8 = uieditfield(app.UIFigure, 'text');
            app.cP8.Editable = 'off';
            app.cP7 = uieditfield(app.UIFigure, 'text');
            app.cP7.Editable = 'off';
            app.cP6 = uieditfield(app.UIFigure, 'text');
            app.cP6.Editable = 'off';
            app.cP5 = uieditfield(app.UIFigure, 'text');
            app.cP5.Editable = 'off';
            app.cP4 = uieditfield(app.UIFigure, 'text');
            app.cP4.Editable = 'off';
            app.cP3 = uieditfield(app.UIFigure, 'text');
            app.cP3.Editable = 'off';
            app.cP2 = uieditfield(app.UIFigure, 'text');
            app.cP2.Editable = 'off';
            app.cP1 = uieditfield(app.UIFigure, 'text');
            app.cP1.Editable = 'off';
            app.cN2 = uieditfield(app.UIFigure, 'text');
            app.cN2.Editable = 'off';
            app.cN3 = uieditfield(app.UIFigure, 'text');
            app.cN3.Editable = 'off';
            app.cN4 = uieditfield(app.UIFigure, 'text');
            app.cN4.Editable = 'off';
            app.cN5 = uieditfield(app.UIFigure, 'text');
            app.cN5.Editable = 'off';
            app.cN10 = uieditfield(app.UIFigure, 'text');
            app.cN10.Editable = 'off';
            app.cN9 = uieditfield(app.UIFigure, 'text');
            app.cN9.Editable = 'off';
            app.cN8 = uieditfield(app.UIFigure, 'text');
            app.cN8.Editable = 'off';
            app.cN7 = uieditfield(app.UIFigure, 'text');
            app.cN7.Editable = 'off';
            app.cN6 = uieditfield(app.UIFigure, 'text');
            app.cN6.Editable = 'off';
            app.cN1 = uieditfield(app.UIFigure, 'text');
            app.cN1.Editable = 'off';
            app.VotingStateLamp = uilamp(app.UIFigure);
            app.VotingStateLampLabel = uilabel(app.UIFigure);
            app.VotingStateLampLabel.Text = 'Voting State';
            app.ResultsButton = uibutton(app.UIFigure, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsButtonPushed, true);
            app.ResultsButton.Text = 'Results';
            app.ElectronicVotingMachineLabel = uilabel(app.UIFigure);
            app.ElectronicVotingMachineLabel.Text = 'Electronic Voting Machine';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1.';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'2.'; ''};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'3.'; ''};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'4.'; ''};
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = {'5.'; ''};
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = {'6.'; ''};
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = {'7.'; ''};
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = {'8.'; ''};
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = {'9.'; ''};
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = {'10.'; ''};
            app.SNoLabel = uilabel(app.UIFigure);
            app.SNoLabel.Text = 'S No.';
            app.CandidateNameLabel = uilabel(app.UIFigure);
            app.CandidateNameLabel.Text = {'Candidate Name'; ''};
            app.CandidatesPartyLabel = uilabel(app.UIFigure);
            app.CandidatesPartyLabel.Text = {'Candidate''s Party'; ''};
            app.PartyLogoLabel = uilabel(app.UIFigure);
            app.PartyLogoLabel.Text = {'Party Logo'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VotingMachine

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end